classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        ISOSliderLabel            matlab.ui.control.Label
        ISOSlider                 matlab.ui.control.Slider
        ASliderLabel              matlab.ui.control.Label
        ASlider                   matlab.ui.control.Slider
        SSliderLabel              matlab.ui.control.Label
        SSlider                   matlab.ui.control.Slider
        ISOValueLabel             matlab.ui.control.Label
        StartButton               matlab.ui.control.StateButton
        AValueLabel               matlab.ui.control.Label
        SValueLabel               matlab.ui.control.Label
        FocusButtonGroup          matlab.ui.container.ButtonGroup
        ForegroundButton          matlab.ui.control.RadioButton
        BackgroundButton          matlab.ui.control.RadioButton
        ImageChoiceDropDownLabel  matlab.ui.control.Label
        ImageChoiceDropDown       matlab.ui.control.DropDown
        EVValueLabel              matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        foreground
        background
        mask
        curr_image
        init_EV = -log2((100 * 1.8 * 1.8) / (1100 * (1 / 28)));
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function calc_current_image(app)
            curr_ISO = app.ISOSlider.Value;
            curr_A = app.ASlider.Value;
            curr_S = app.SSlider.Value;
            curr_EV = -log2((100 * curr_A * curr_A) / (curr_ISO * curr_S));
            app.EVValueLabel.Text = ['Current EV Value:', num2str(roundn(curr_EV, -3))];
            delta_EV = curr_EV - app.init_EV;
            delta_pix = delta_EV / 9;
            if app.FocusButtonGroup.SelectedObject.Text == 'Foreground'
                curr_foreground = app.foreground + delta_pix;
                curr_background = app.filter_image(app.background) + delta_pix;
            else
                curr_foreground = app.filter_image(app.foreground) + delta_pix;
                curr_background = app.background + delta_pix;
            end
            app.curr_image = curr_background .* (1 - app.mask) + curr_foreground .* app.mask;
            app.Image.ImageSource = app.curr_image;
        end
        
        
        function result = filter_image(app, image)
            filter = fspecial('average', round(27/(app.ASlider.Value)/(app.ASlider.Value)+1));
            result = imfilter(image, filter, 'same', 'conv');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ISOValueLabel.Text = num2str(app.ISOSlider.Value);
            app.AValueLabel.Text = num2str(app.ASlider.Value);
            app.SValueLabel.Text = num2str(rats(app.SSlider.Value));
            [app.foreground, ~, app.mask] = imread('lounge-hdr-foreground.png');
            app.background = imread('lounge-hdr-background.png');
            app.foreground = im2double(app.foreground);
            app.background = im2double(app.background);
            app.mask = im2double(app.mask);
            app.EVValueLabel.Text = ['Current EV Value:', num2str(roundn(app.init_EV, -3))];
            app.Image.ImageSource = zeros(size(app.foreground));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {283, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: ISOSlider
        function ISOSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ISOValueLabel.Text = num2str(round(changingValue));
            if app.StartButton.Value == 1
                calc_current_image(app);
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            value = app.StartButton.Value;
            if value == 1
                calc_current_image(app);
            end
            if value == 0
                app.Image.ImageSource = zeros(size(app.foreground));
            end
        end

        % Value changing function: ASlider
        function ASliderValueChanging(app, event)
            changingValue = event.Value;
            app.AValueLabel.Text = num2str(roundn(changingValue, -1));
            if app.StartButton.Value == 1
                calc_current_image(app);
            end
        end

        % Value changing function: SSlider
        function SSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > 0.1
                app.SValueLabel.Text = num2str(roundn(changingValue, -1));
            else
                intValue = num2str(round(1 / changingValue));
                app.SValueLabel.Text = ['1/', intValue];
            end
            
            if app.StartButton.Value == 1
                calc_current_image(app);
            end
        end

        % Selection changed function: FocusButtonGroup
        function FocusButtonGroupSelectionChanged(app, event)
            if app.StartButton.Value == 1
                calc_current_image(app);
            end
        end

        % Value changed function: ImageChoiceDropDown
        function ImageChoiceDropDownValueChanged(app, event)
            value = app.ImageChoiceDropDown.Value;
            if value == 'Image 1'
                app.ISOSlider.Value = 1100;
                app.ASlider.Value = 1.8;
                app.SSlider.Value = 1/28;
                [app.foreground, ~, app.mask] = imread('lounge-hdr-foreground.png');
                app.background = imread('lounge-hdr-background.png');
                app.init_EV = -log2((100 * 1.8 * 1.8) / (1100 * (1 / 28)));
            elseif value == 'Image 2'
                app.ISOSlider.Value = 100;
                app.ASlider.Value = 1.8;
                app.SSlider.Value = 1/100;
                [app.foreground, ~, app.mask] = imread('forground_1.png');
                app.background = imread('background_1.png');
                app.init_EV = -log2((100 * 1.8 * 1.8) / (100 * (1 / 100)));
            end
            app.ISOValueLabel.Text = num2str(app.ISOSlider.Value);
            app.AValueLabel.Text = num2str(app.ASlider.Value);
            app.SValueLabel.Text = num2str(rats(app.SSlider.Value));
            app.foreground = im2double(app.foreground);
            app.background = im2double(app.background);
            app.mask = im2double(app.mask);
            app.EVValueLabel.Text = ['Current EV Value:', num2str(roundn(app.init_EV, -3))];
            if app.StartButton.Value == 1
                calc_current_image(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ISOSliderLabel = uilabel(app.LeftPanel);
            app.ISOSliderLabel.Text = 'ISO';
            app.ISOSlider = uislider(app.LeftPanel);
            app.ISOSlider.Limits = [100 6400];
            app.ISOSlider.ValueChangingFcn = createCallbackFcn(app, @ISOSliderValueChanging, true);
            app.ISOSlider.Value = 1100;
            app.ASliderLabel = uilabel(app.LeftPanel);
            app.ASliderLabel.Text = 'A';
            app.ASlider = uislider(app.LeftPanel);
            app.ASlider.Limits = [1.8 32];
            app.ASlider.ValueChangingFcn = createCallbackFcn(app, @ASliderValueChanging, true);
            app.ASlider.Value = 1.8;
            app.SSliderLabel = uilabel(app.LeftPanel);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.LeftPanel);
            app.SSlider.Limits = [0.00048828125 2];
            app.SSlider.MajorTicks = [0.00048828125 0.40048828125 0.80048828125 1.20048828125 1.60048828125 2];
            app.SSlider.MajorTickLabels = {'1/2048', '0.4', '0.8', '1.2', '1.6', '2'};
            app.SSlider.ValueChangingFcn = createCallbackFcn(app, @SSliderValueChanging, true);
            app.SSlider.MinorTicks = [0.00048828125 0.05048828125 0.10048828125 0.15048828125 0.20048828125 0.25048828125 0.30048828125 0.35048828125 0.40048828125 0.45048828125 0.50048828125 0.55048828125 0.60048828125 0.65048828125 0.70048828125 0.75048828125 0.80048828125 0.85048828125 0.90048828125 0.95048828125 1.00048828125 1.05048828125 1.10048828125 1.15048828125 1.20048828125 1.25048828125 1.30048828125 1.35048828125 1.40048828125 1.45048828125 1.50048828125 1.55048828125 1.60048828125 1.65048828125 1.70048828125 1.75048828125 1.80048828125 1.85048828125 1.90048828125 1.95048828125 2];
            app.SSlider.Value = 0.0357142857142857;
            app.ISOValueLabel = uilabel(app.LeftPanel);
            app.ISOValueLabel.Text = 'ISOValueLabel';
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.AValueLabel = uilabel(app.LeftPanel);
            app.AValueLabel.Text = 'AValueLabel';
            app.SValueLabel = uilabel(app.LeftPanel);
            app.SValueLabel.Text = 'SValueLabel';
            app.FocusButtonGroup = uibuttongroup(app.LeftPanel);
            app.FocusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FocusButtonGroupSelectionChanged, true);
            app.FocusButtonGroup.Title = 'Focus';
            app.ForegroundButton = uiradiobutton(app.FocusButtonGroup);
            app.ForegroundButton.Text = 'Foreground';
            app.ForegroundButton.Value = true;
            app.BackgroundButton = uiradiobutton(app.FocusButtonGroup);
            app.BackgroundButton.Text = 'Background';
            app.ImageChoiceDropDownLabel = uilabel(app.LeftPanel);
            app.ImageChoiceDropDownLabel.Text = 'Image Choice';
            app.ImageChoiceDropDown = uidropdown(app.LeftPanel);
            app.ImageChoiceDropDown.Items = {'Image 1', 'Image 2'};
            app.ImageChoiceDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageChoiceDropDownValueChanged, true);
            app.ImageChoiceDropDown.Value = 'Image 1';
            app.EVValueLabel = uilabel(app.LeftPanel);
            app.EVValueLabel.Text = {'EVValue'; ''};
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end