classdef problem1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        FunctionDropDownLabel  matlab.ui.control.Label
        FunctionDropDown       matlab.ui.control.DropDown
        Param1SliderLabel      matlab.ui.control.Label
        Param1Slider           matlab.ui.control.Slider
        Param2SliderLabel      matlab.ui.control.Label
        Param2Slider           matlab.ui.control.Slider
        Param1Label            matlab.ui.control.Label
        Param2Label            matlab.ui.control.Label
        GenerateButton         matlab.ui.control.Button
        Param3Label            matlab.ui.control.Label
        Param3SliderLabel      matlab.ui.control.Label
        Param3Slider           matlab.ui.control.Slider
        Param4Label            matlab.ui.control.Label
        Param4SliderLabel      matlab.ui.control.Label
        Param4Slider           matlab.ui.control.Slider
        Param5Label            matlab.ui.control.Label
        Param5SliderLabel      matlab.ui.control.Label
        Param5Slider           matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        Image_2                matlab.ui.control.Image
        Image_3                matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function VisibleAdjust(app, state1, state2, state3, state4, state5) %adjust visibility
            app.Param1Slider.Visible = state1;
            app.Param1Label.Visible = state1;
            app.Param1SliderLabel.Visible = state1;
            app.Param2Slider.Visible = state2;
            app.Param2Label.Visible = state2;
            app.Param2SliderLabel.Visible = state2;
            app.Param3Slider.Visible = state3;
            app.Param3Label.Visible = state3;
            app.Param3SliderLabel.Visible = state3;
            app.Param4Slider.Visible = state4;
            app.Param4Label.Visible = state4;
            app.Param4SliderLabel.Visible = state4;
            app.Param5Slider.Visible = state5;
            app.Param5Label.Visible = state5;
            app.Param5SliderLabel.Visible = state5;
        end
        
        function results = GenPulse(~, xCoor, yCoor)
            results = zeros(256, 256);
            results(xCoor, yCoor) = 1;
        end
        
        function results = GenSin(~, Freq, Angle, Phase)
            [Xaxis, Yaxis] = meshgrid(1:256);
            Angle = Angle * pi / 180;
            Phase = Phase * pi / 180;
            results = sin(2 * pi * Freq * (Xaxis * cos(Angle) + Yaxis * sin(Angle)) + Phase);
        end
        
        function results = GenRec(~, xCoor, yCoor, Angle, xLength, yLength)
            results = zeros(256, 256);
            xStart = max(floor((xCoor - xLength / 2)), 1);
            yStart = max(floor((yCoor - yLength / 2)), 1);
            xEnd = min(floor((xCoor + xLength / 2)), 256);
            yEnd = min(floor((yCoor + yLength / 2)), 256);
            results(xStart:xEnd, yStart:yEnd) = 1;
            results = imrotate(results, Angle, "bicubic", "crop");
        end
        
        function results = GenGauss(~, SD)
            [Xaxis, Yaxis] = meshgrid(1:256);
            results = exp(-((Xaxis - 128) .* (Xaxis - 128) + (Yaxis - 128) .* (Yaxis - 128)) / (2 * SD));
        end
        
        function results = GenGabor(~, Freq, Angle, Phase, SD)
            [Xaxis, Yaxis] = meshgrid(1:256);
            Angle = Angle * pi / 180;
            Phase = Phase * pi / 180;
            results = sin(2 * pi * Freq * (Xaxis * cos(Angle) + Yaxis * sin(Angle)) + Phase);
            results = results .* exp(-((Xaxis - 128) .* (Xaxis - 128) + (Yaxis - 128) .* (Yaxis - 128)) / (2 * SD));
        end
        
        function results = DFT(~, Image)
            index_matrix = 0:1:255;
            index_matrix = index_matrix .* transpose(index_matrix);
            omega = exp(-2 * pi * 1i / 256);
            index_matrix = omega .^ index_matrix;
            results = index_matrix * Image * index_matrix;
            results = circshift(results, 128, 1);
            results = circshift(results, 128, 2);
        end
        
        function results = Normalize(~, Image)
            maxNum = max(max(Image));
            minNum = min(min(Image));
            results = (Image - minNum) / (maxNum - minNum);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            VisibleAdjust(app, true, true, false, false, false);
            app.Param1Slider.Limits = [1, 256];
            app.Param1Slider.Value = 128;
            app.Param1SliderLabel.Text = 'x coor';
            app.Param1Label.Text = num2str(app.Param1Slider.Value);
            app.Param2Slider.Limits = [1, 256];
            app.Param2Slider.Value = 128;
            app.Param2SliderLabel.Text = 'y coor';
            app.Param2Label.Text = num2str(app.Param2Slider.Value);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {509, 509};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {375, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            value = app.FunctionDropDown.Value;
            if strcmp(value, 'Pulse')
                VisibleAdjust(app, true, true, false, false, false);
                app.Param1Slider.Limits = [1, 256];
                app.Param1Slider.Value = 128;
                app.Param1SliderLabel.Text = 'x coor';
                app.Param1Label.Text = num2str(app.Param1Slider.Value);
                app.Param2Slider.Limits = [1, 256];
                app.Param2Slider.Value = 128;
                app.Param2SliderLabel.Text = 'y coor';
                app.Param2Label.Text = num2str(app.Param2Slider.Value);
            elseif strcmp(value, 'Sin')
                VisibleAdjust(app, true, true, true, false, false);
                app.Param1Slider.Limits = [0,0.2];
                app.Param1Slider.Value = 0.025;
                app.Param1SliderLabel.Text = 'Freq';
                app.Param1Label.Text = num2str(app.Param1Slider.Value);
                app.Param2Slider.Limits = [0,360];
                app.Param2Slider.Value = 0;
                app.Param2SliderLabel.Text = 'Angle';
                app.Param2Label.Text = num2str(app.Param2Slider.Value);
                app.Param3Slider.Limits = [0,360];
                app.Param3Slider.Value = 0;
                app.Param3SliderLabel.Text = 'Phase';
                app.Param3Label.Text = num2str(app.Param3Slider.Value);
            elseif strcmp(value, 'Rectangle')
                VisibleAdjust(app, true, true, true, true, true);
                app.Param1Slider.Limits = [1, 256];
                app.Param1Slider.Value = 128;
                app.Param1SliderLabel.Text = 'x coor';
                app.Param1Label.Text = num2str(app.Param1Slider.Value);
                app.Param2Slider.Limits = [1, 256];
                app.Param2Slider.Value = 128;
                app.Param2SliderLabel.Text = 'y coor';
                app.Param2Label.Text = num2str(app.Param2Slider.Value);
                app.Param3Slider.Limits = [0,360];
                app.Param3Slider.Value = 0;
                app.Param3SliderLabel.Text = 'Angle';
                app.Param3Label.Text = num2str(app.Param3Slider.Value);
                app.Param4Slider.Limits = [1, 256];
                app.Param4Slider.Value = 128;
                app.Param4SliderLabel.Text = 'x Length';
                app.Param4Label.Text = num2str(app.Param4Slider.Value);
                app.Param5Slider.Limits = [1, 256];
                app.Param5Slider.Value = 128;
                app.Param5SliderLabel.Text = 'y Length';
                app.Param5Label.Text = num2str(app.Param5Slider.Value);
            elseif strcmp(value, 'Gauss')
                VisibleAdjust(app, true, false, false, false, false);
                app.Param1Slider.Limits = [1, 1000];
                app.Param1Slider.Value = 100;
                app.Param1SliderLabel.Text = 'SD';
                app.Param1Label.Text = num2str(app.Param1Slider.Value);
            elseif strcmp(value, 'Gabor')
                VisibleAdjust(app, true, true, true, true, false);
                app.Param1Slider.Limits = [0,0.2];
                app.Param1Slider.Value = 0.1;
                app.Param1SliderLabel.Text = 'Freq';
                app.Param1Label.Text = num2str(app.Param1Slider.Value);
                app.Param2Slider.Limits = [0,360];
                app.Param2Slider.Value = 0;
                app.Param2SliderLabel.Text = 'Angle';
                app.Param2Label.Text = num2str(app.Param2Slider.Value);
                app.Param3Slider.Limits = [0,360];
                app.Param3Slider.Value = 0;
                app.Param3SliderLabel.Text = 'Phase';
                app.Param3Label.Text = num2str(app.Param3Slider.Value);
                app.Param4Slider.Limits = [1, 1000];
                app.Param4Slider.Value = 100;
                app.Param4SliderLabel.Text = 'SD';
                app.Param4Label.Text = num2str(app.Param4Slider.Value);
            end
        end

        % Value changing function: Param1Slider
        function Param1SliderValueChanging(app, event)
            changingValue = event.Value;
            if strcmp(app.FunctionDropDown.Value, 'Sin') || strcmp(app.FunctionDropDown.Value, 'Gabor')
                app.Param1Label.Text = num2str(roundn(changingValue, -3));
            else
                app.Param1Label.Text = num2str(round(changingValue));
            end
        end

        % Value changing function: Param2Slider
        function Param2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Param2Label.Text = num2str(round(changingValue));
        end

        % Value changing function: Param3Slider
        function Param3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Param3Label.Text = num2str(round(changingValue));
        end

        % Value changing function: Param4Slider
        function Param4SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Param4Label.Text = num2str(round(changingValue));
        end

        % Value changing function: Param5Slider
        function Param5SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Param5Label.Text = num2str(round(changingValue));
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            xAxis = 1:256;
            yAxis = 1:256;
            if strcmp(app.FunctionDropDown.Value, 'Pulse')
                initImage = GenPulse(app, round(app.Param1Slider.Value), round(app.Param2Slider.Value));
            elseif strcmp(app.FunctionDropDown.Value, 'Sin')
                initImage = GenSin(app, app.Param1Slider.Value, app.Param2Slider.Value, app.Param3Slider.Value);
            elseif strcmp(app.FunctionDropDown.Value, 'Rectangle')
                initImage = GenRec(app, app.Param1Slider.Value, app.Param2Slider.Value, app.Param3Slider.Value, app.Param4Slider.Value, app.Param5Slider.Value);
            elseif strcmp(app.FunctionDropDown.Value, 'Gauss')
                initImage = GenGauss(app, app.Param1Slider.Value);
            elseif strcmp(app.FunctionDropDown.Value, 'Gabor')
                initImage = GenGabor(app, app.Param1Slider.Value, app.Param2Slider.Value, app.Param3Slider.Value, app.Param4Slider.Value);
            end

            
            app.Image.ImageSource = repmat(initImage, 1, 1, 3);
            s1 = surf(app.UIAxes, initImage(xAxis, yAxis));
            s1.EdgeColor = 'none';
            
            DFTImage = DFT(app, initImage);
            app.Image_2.ImageSource = repmat(Normalize(app, abs(DFTImage)), 1, 1, 3);
            s2 = surf(app.UIAxes_2, abs(DFTImage(xAxis, yAxis)));
            s2.EdgeColor = 'none';
            
            angleImage = angle(DFTImage);
            app.Image_3.ImageSource = repmat(Normalize(app, angleImage), 1, 1, 3);
            s3 = surf(app.UIAxes_3, angleImage(xAxis, yAxis));
            s3.EdgeColor = 'none';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FunctionDropDownLabel = uilabel(app.LeftPanel);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.LeftPanel);
            app.FunctionDropDown.Items = {'Pulse', 'Sin', 'Rectangle', 'Gauss', 'Gabor'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'Pulse';
            app.Param1SliderLabel = uilabel(app.LeftPanel);
            app.Param1SliderLabel.Text = 'Param1';
            app.Param1Slider = uislider(app.LeftPanel);
            app.Param1Slider.ValueChangingFcn = createCallbackFcn(app, @Param1SliderValueChanging, true);
            app.Param2SliderLabel = uilabel(app.LeftPanel);
            app.Param2SliderLabel.Text = 'Param2';
            app.Param2Slider = uislider(app.LeftPanel);
            app.Param2Slider.ValueChangingFcn = createCallbackFcn(app, @Param2SliderValueChanging, true);
            app.Param1Label = uilabel(app.LeftPanel);
            app.Param1Label.Text = 'Param1Label';
            app.Param2Label = uilabel(app.LeftPanel);
            app.Param2Label.Text = 'Param2Label';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.Param3Label = uilabel(app.LeftPanel);
            app.Param3Label.Text = 'Param3Label';
            app.Param3SliderLabel = uilabel(app.LeftPanel);
            app.Param3SliderLabel.Text = 'Param3';
            app.Param3Slider = uislider(app.LeftPanel);
            app.Param3Slider.ValueChangingFcn = createCallbackFcn(app, @Param3SliderValueChanging, true);
            app.Param4Label = uilabel(app.LeftPanel);
            app.Param4Label.Text = 'Param4Label';
            app.Param4SliderLabel = uilabel(app.LeftPanel);
            app.Param4SliderLabel.Text = 'Param4';
            app.Param4Slider = uislider(app.LeftPanel);
            app.Param4Slider.ValueChangingFcn = createCallbackFcn(app, @Param4SliderValueChanging, true);
            app.Param5Label = uilabel(app.LeftPanel);
            app.Param5Label.Text = 'Param5Label';
            app.Param5SliderLabel = uilabel(app.LeftPanel);
            app.Param5SliderLabel.Text = 'Param5';
            app.Param5Slider = uislider(app.LeftPanel);
            app.Param5Slider.ValueChangingFcn = createCallbackFcn(app, @Param5SliderValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image_2 = uiimage(app.RightPanel);
            app.Image_3 = uiimage(app.RightPanel);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = problem1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end