classdef problem2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        HSliderLabel        matlab.ui.control.Label
        HSlider             matlab.ui.control.Slider
        SSliderLabel        matlab.ui.control.Label
        SSlider             matlab.ui.control.Slider
        ImageDropDownLabel  matlab.ui.control.Label
        ImageDropDown       matlab.ui.control.DropDown
        GenerateButton      matlab.ui.control.StateButton
        CurrentHLabel       matlab.ui.control.Label
        CurrentSLabel       matlab.ui.control.Label
        RightPanel          matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        Image2              matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        currentHSV;
        currentMask;
        srcimg;
        RecROI;
        hsvROI
    end
    
    methods (Access = private)
        
        function mask = genMask(app, img)
            [height, width, ~] = size(img);
            RecMask = zeros(height, width);
            RecMask(app.RecROI(1):app.RecROI(2), app.RecROI(3):app.RecROI(4)) = 1;
            hsvMask = (img(:, :, 1) >= app.hsvROI(1)) .* (img(:, :, 1) <= app.hsvROI(2));
            hsvMask = hsvMask .* (img(:, :, 2) >= app.hsvROI(3)) .* (img(:, :, 2) <= app.hsvROI(4));
            hsvMask = hsvMask .* (img(:, :, 3) >= app.hsvROI(5)) .* (img(:, :, 3) <= app.hsvROI(6));
            mask = RecMask .* hsvMask;
            se = strel('disk', 1);
            mask = bwareaopen(mask, 64, 8);
            mask = imdilate(mask, se);
        end
        
        function result = genHSV(app)
            [height, width, ~] = size(app.currentHSV);
            newHSV = app.currentHSV;
            newHSV(find(app.currentMask)) = (app.HSlider.Value) / 360;
            newHSV(find(app.currentMask) + height * width) = app.SSlider.Value;
            newRGB = hsv2rgb(newHSV);
            app.Image.ImageSource = newRGB;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CurrentSLabel.Text = "0";
            app.CurrentHLabel.Text = "0";
            app.srcimg = imread('1.png');
            app.RecROI = [200, 500, 1, 310];
            app.hsvROI = [0.6, 0.8, 0.40, 0.95, 0.15, 0.7];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {684, 684};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changing function: SSlider
        function SSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CurrentSLabel.Text = num2str(roundn(changingValue, -2));
            if app.GenerateButton.Value == 1
                app.genHSV();
            end
        end

        % Value changing function: HSlider
        function HSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CurrentHLabel.Text = num2str(round(changingValue));
            if app.GenerateButton.Value == 1
                app.genHSV();
            end
        end

        % Value changed function: ImageDropDown
        function ImageDropDownValueChanged(app, event)
            value = app.ImageDropDown.Value;
            if strcmp(value, 'Image1')
                app.srcimg = imread('1.png');
                app.RecROI = [200, 500, 1, 310];
                app.hsvROI = [0.6, 0.8, 0.40, 0.95, 0.15, 0.7];
            elseif strcmp(value, 'Image2')
                app.srcimg = imread('2.png');
                app.RecROI = [300, 1000, 400, 1000];
                app.hsvROI = [0, 1, 0.6, 1, 0.25, 1];
            elseif strcmp(value, 'Image3')
                app.srcimg = imread('3.jpg');
                app.RecROI = [200, 853, 90, 640];
                app.hsvROI = [0.5, 0.85, 0.5, 1, 0.1, 1];
            elseif strcmp(value, 'Image4')
                app.srcimg = imread('4.png');
                app.RecROI = [150, 600, 1, 400];
                app.hsvROI = [0, 0.3, 0.36, 1.0, 0.65, 1.0];
            elseif strcmp(value, 'Image5')
                app.srcimg = imread('5.png');
                app.RecROI = [430, 800, 200, 600];
                app.hsvROI = [0.2, 0.5, 0.2, 1.0, 0.0, 1.0];
            elseif strcmp(value, 'Image6')
                app.srcimg = imread('6.png');
                app.RecROI = [1, 818, 1, 700];
                app.hsvROI = [0.9, 1.0, 0.2, 1.0, 0.4, 1.0];
            elseif strcmp(value, 'Image7')
                app.srcimg = imread('7.png');
                app.RecROI = [200, 1000, 200, 800];
                app.hsvROI = [0.15, 0.7, 0.0, 1.0, 0.0, 1.0];
            end
            app.currentHSV = rgb2hsv(app.srcimg);
            app.Image2.ImageSource = app.srcimg;
            app.currentMask = app.genMask(app.currentHSV);
            if app.GenerateButton.Value == 1
                app.genHSV();
            end
        end

        % Value changed function: GenerateButton
        function GenerateButtonValueChanged(app, event)
            value = app.GenerateButton.Value;
            if value == 1
                app.currentHSV = rgb2hsv(app.srcimg);
                app.Image2.ImageSource = app.srcimg;
                app.currentMask = app.genMask(app.currentHSV);
                app.genHSV();
            else
                app.Image.ImageSource = app.srcimg;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HSliderLabel = uilabel(app.LeftPanel);
            app.HSliderLabel.Text = 'H';
            app.HSlider = uislider(app.LeftPanel);
            app.HSlider.Limits = [0 360];
            app.HSlider.ValueChangingFcn = createCallbackFcn(app, @HSliderValueChanging, true);
            app.SSliderLabel = uilabel(app.LeftPanel);
            app.SSliderLabel.Text = 'S';
            app.SSlider = uislider(app.LeftPanel);
            app.SSlider.Limits = [0 1];
            app.SSlider.ValueChangingFcn = createCallbackFcn(app, @SSliderValueChanging, true);
            app.ImageDropDownLabel = uilabel(app.LeftPanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.LeftPanel);
            app.ImageDropDown.Items = {'Image1', 'Image2', 'Image3', 'Image4', 'Image5', 'Image6', 'Image7'};
            app.ImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageDropDownValueChanged, true);
            app.ImageDropDown.Value = 'Image1';
            app.GenerateButton = uibutton(app.LeftPanel, 'state');
            app.GenerateButton.ValueChangedFcn = createCallbackFcn(app, @GenerateButtonValueChanged, true);
            app.GenerateButton.Text = 'Generate';
            app.CurrentHLabel = uilabel(app.LeftPanel);
            app.CurrentHLabel.Text = 'CurrentH';
            app.CurrentSLabel = uilabel(app.LeftPanel);
            app.CurrentSLabel.Text = 'CurrentS';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image2 = uiimage(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = problem2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end